classdef ARMain_FolderFileImageReviewPanels_29March < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        AnnotationsSwitch           matlab.ui.control.Switch
        ImageEditFieldLabel         matlab.ui.control.Label
        AnnotationsSwitchLabel      matlab.ui.control.Label
        ImageEditField              matlab.ui.control.EditField
        ENLARGEButton               matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        ReviewPanel                 matlab.ui.container.Panel
        OpenExcelButton             matlab.ui.control.Button
        NotesTextArea               matlab.ui.control.TextArea
        NotesTextAreaLabel          matlab.ui.control.Label
        SaveNextButton              matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        FinalDecisionTBButtonGroup  matlab.ui.container.ButtonGroup
        TBNegativeFinalD            matlab.ui.control.RadioButton
        TBPositiveFinalD            matlab.ui.control.RadioButton
        TBX11KTBButtonGroup         matlab.ui.container.ButtonGroup
        TBNegativeGroundT           matlab.ui.control.RadioButton
        TBPositiveGroundT           matlab.ui.control.RadioButton
        NNManualPanel               matlab.ui.container.Panel
        HilarEnlargementManualR     matlab.ui.control.CheckBox
        PleuralEffusionManualR      matlab.ui.control.CheckBox
        CavitaryLesionManualR       matlab.ui.control.CheckBox
        ConsolidationManualR        matlab.ui.control.CheckBox
        MiliaryManualR              matlab.ui.control.CheckBox
        NoduleManualR               matlab.ui.control.CheckBox
        NoTBManualR                 matlab.ui.control.CheckBox
        HilarEnlargementNNR         matlab.ui.control.CheckBox
        PleuralEffusionNNR          matlab.ui.control.CheckBox
        ConsolidationNNR            matlab.ui.control.CheckBox
        CavitaryLesionNNR           matlab.ui.control.CheckBox
        MiliaryNNR                  matlab.ui.control.CheckBox
        NoduleNNR                   matlab.ui.control.CheckBox
        NoTBNNR                     matlab.ui.control.CheckBox
        FeaturesPanel               matlab.ui.container.Panel
        HilarEnlargementLabel       matlab.ui.control.Label
        PleuralEffusionLabel        matlab.ui.control.Label
        ConsolidationLabel          matlab.ui.control.Label
        CavitaryLesionLabel         matlab.ui.control.Label
        MiliaryLabel                matlab.ui.control.Label
        NoduleLabel                 matlab.ui.control.Label
        NoTBLabel                   matlab.ui.control.Label
        FilePanel                   matlab.ui.container.Panel
        DISPLAYFile                 matlab.ui.control.Button
        ImagenamesDropDown          matlab.ui.control.DropDown
        ImagenamesDropDownLabel     matlab.ui.control.Label
        NEXTFile                    matlab.ui.control.Button
        BACKFile                    matlab.ui.control.Button
        FolderPanel                 matlab.ui.container.Panel
        FoldernameEditField         matlab.ui.control.EditField
        FoldernameEditFieldLabel    matlab.ui.control.Label
        CHOOSEFolder                matlab.ui.control.Button
    end

    
    properties (Access = private)
        selectedFolder; % Description
        pairedCEFolder; % Still not sure what this does 
        pairedAnnFolder;
        nextFolder;%May not be using
        b;
        currentIm;
        i = 1; %Next button troubleshooting 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: CHOOSEFolder
        function CHOOSEFolderPushed(app, event)
           
            
           app.selectedFolder = uigetdir(); %user select folder 
            
           [~,name] = fileparts(app.selectedFolder); %gets name of folder 
           app.FoldernameEditField.Value = name;
           folderlist = dir(app.selectedFolder); %structure array
           app.b = {folderlist(:).name}';%cell array
           app.b(ismember(app.b,{'.','..'})) = [];
           app.ImagenamesDropDown.Items = app.b;


        end

        % Callback function
        function CHOOSEFilePushed(app, event)
          
        end

        % Button pushed function: DISPLAYFile
        function DISPLAYFilePushed(app, event)


            value = app.ImagenamesDropDown.Value;
            
            filename = [app.selectedFolder, '/', value];
            %filename = [app.nextFolder, '/', value];
            
            app.currentIm = imread(filename);

            imshow(app.currentIm,'Parent',app.UIAxes);
            
            app.ImageEditField.Value = app.ImagenamesDropDown.Value;
            
            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
          
            pairedIm = append(a, '_CE.png');
            %app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');


            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
            imshow(pairedIm, 'Parent', app.UIAxes2);

%             [rows, ~] = size(pairedIm);
%             %CE_OG = ROI(:, 1 : rows);
%             pairedIm = pairedIm(:, rows+1 : rows*2 );
%             imshow(pairedIm, 'Parent', app.UIAxes2);
%             app.AnnotationsSwitch.Value = 'On';
            

            pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw = fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            data = jsondecode(str);
            disp(data); 
            Classification = string({data.objects.classTitle})';
            T = table(Classification);


            indCav = T.Classification == 'cavitary lesion';
            indCav = str(indCav);
            TF = isempty(indCav);
            if TF == 1
                app.CavitaryLesionManualR.Value = 0;
            else 
                app.CavitaryLesionManualR.Value = 1;
            end  

            indCon = T.Classification == 'consolidation';
            indCon = str(indCon);
            TF = isempty(indCon);
            if TF == 1
                app.ConsolidationManualR.Value = 0;
            else 
                app.ConsolidationManualR.Value = 1;
            end  

            indHil = T.Classification == 'hilar lymphadenopathy';
            indHil = str(indHil);
            TF = isempty(indHil);
            if TF == 1
                app.HilarEnlargementManualR.Value = 0;
            else 
                app.HilarEnlargementManualR.Value = 1;
            end  
           

            indMil = T.Classification == 'milieary pattern';
            indMil = str(indMil);
            TF = isempty(indMil);
            if TF == 1
                app.MiliaryManualR.Value = 0;
            else 
                app.MiliaryManualR.Value = 1;
            end  

            indNodule = T.Classification == 'nodule(s)';
            indNodule = str(indNodule);
            TF = isempty(indNodule);
            if TF == 1
                app.NoduleManualR.Value = 0;
            else 
                app.NoduleManualR.Value = 1;
            end 

            indNA = T.Classification == 'no features of TB appreciated';
            indNA = str(indNA);
            TF = isempty(indNA);
            if TF == 1
                app.NoTBManualR.Value = 0;
            else 
                app.NoTBManualR.Value = 1;
            end 

            indPleuralEf = T.Classification == 'pleural effusion';
            indPleuralEf = str(indPleuralEf);
            TF = isempty(indPleuralEf);
            if TF == 1
                app.PleuralEffusionManualR.Value = 0;
            else 
                app.PleuralEffusionManualR.Value = 1;
            end 


            %pairedJson = imread(pairedFileAnn);
        

        end

        % Value changed function: ImagenamesDropDown
        function ImagenamesDropDownValueChanged(app, event)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        
            
            folder = app.FoldernameEditField.Value; %need to extract only smallest folder
            %currently full pathname

            finalD = app.FinalDecisionTBButtonGroup.SelectedObject.Text;
%           disp (class(finalD));
            %finalD = genvarname(finalD);
            
            Time = now; 
            dateTime = datetime(Time,'ConvertFrom','datenum'); %is date necessary?
            %just in case user wants to know last date they worked on/how
            %long it is taking them to review through images/annotations
            
            dateTime = datestr(dateTime);


            fileName = app.ImagenamesDropDown.Value;
            setNumber = sscanf(folder,'Set %d');
            setNumber = string(setNumber);

            notes = app.NotesTextArea.Value;

           
            notesDecision = [fileName finalD notes dateTime]; %date/time 
%           included if needed 
    writecell(notesDecision,'TBnotes.xlsx','Sheet',setNumber, 'WriteMode', 'append');
    uiwait(msgbox('Notes and Decision Saved', 'Success', 'modal'));
%     system('open TBnotes.xlsx');

return


%           notesDecision = [folder fileName finalD notes];
%           disp(notesDecision);
            notesDecision = cellstr(notesDecision);
            notesDecision = cell2table(notesDecision);
%           notesDecision = renamevars(notesDecision, 'Image', 'Decision', 'Notes', 'Time');

%             notesDecision.(1) = 'Image';
%             notesDecision.(2) = 'Final Decision';
%             notesDecision.(3) = 'Notes';
%             notesDecision.(4) = 'Time';

            Sr_No = {'Image'};
            Sr_No1 = {'Decision'};
            Sr_No2 = {'Notes'};
            final_Data = [Sr_No];

% 
%             system('open TBnotes.xlsx');
            %winopen('TBnotes.xlsx'); %works with windows 
            %macopen('TBnotes.xlsx';)


        end

        % Button pushed function: SaveNextButton
        function SaveNextButtonPushed(app, event)
            % figure out how to NOT re-write the notes that were saved in 
            %SaveButton--> overwrite 

            folder = app.FoldernameEditField.Value;

            finalD = app.FinalDecisionTBButtonGroup.SelectedObject.Text;

            
            Time = now; 
            dateTime = datetime(Time,'ConvertFrom','datenum'); %is date necessary?
            %just in case user wants to know last date they worked on/how
            %long it is taking them to review through images/annotations
            
            dateTime = datestr(dateTime);
 
           
            fileName = app.ImagenamesDropDown.Value;
            setNumber = sscanf(folder,'Set %d');
            setNumber = string(setNumber);

            notes = app.NotesTextArea.Value;
           
            notesDecision = [fileName finalD notes dateTime]; %date/time 
%           included if needed 
            writecell(notesDecision,'TBnotes.xlsx','Sheet',setNumber, 'WriteMode', 'append');


% 
%             system('open TBnotes.xlsx');
            %winopen('TBnotes.xlsx'); %works with windows 
            %macopen('TBnotes.xlsx';)
            
            
            uiwait(msgbox('Notes and Decision Saved', 'Success', 'modal'));
%             system('open TBnotes.xlsx');

             app.NotesTextArea.Value = ' ';
            try 
             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
             %a = str2num(a);
             a = str2double(a);
             c = a + 1;
            % disp(c);
             d = num2str(c);
            % disp(d);
             nextIm =  append(d, '.png');
            % disp(newIm)
             app.ImagenamesDropDown.Value = nextIm;
             filename = [app.selectedFolder, '/', nextIm];             
             nextIm = imread(filename);
             imshow(nextIm, 'Parent', app.UIAxes)
             app.ImageEditField.Value = app.ImagenamesDropDown.Value;
             %app.UIAxes.Title.String = app.ImagenamesDropDown.Value;


            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
            pairedIm = append(a, '_CE.png');
            %app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');
            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
            
%             [rows, ~] = size(pairedIm);
%             %CE_OG = ROI(:, 1 : rows);
%             pairedIm = pairedIm(:, rows+1 : rows*2 );
            
            imshow(pairedIm, 'Parent', app.UIAxes2);
            app.AnnotationsSwitch.Value = 'On';


            pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw=fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            data = jsondecode(str);
            %disp(data); 
            Classification = string({data.objects.classTitle})';
            T = table(Classification);


            indCav = T.Classification == 'cavitary lesion';
            indCav = str(indCav);
            TF = isempty(indCav);
            if TF == 1
                app.CavitaryLesionManualR.Value = 0;
            else 
                app.CavitaryLesionManualR.Value = 1;
            end  

            indCon = T.Classification == 'consolidation';
            indCon = str(indCon);
            TF = isempty(indCon);
            if TF == 1
                app.ConsolidationManualR.Value = 0;
            else 
                app.ConsolidationManualR.Value = 1;
            end  

            indHil = T.Classification == 'hilar lymphadenopathy';
            indHil = str(indHil);
            TF = isempty(indHil);
            if TF == 1
                app.HilarEnlargementManualR.Value = 0;
            else 
                app.HilarEnlargementManualR.Value = 1;
            end  
           

            indMil = T.Classification == 'milieary pattern';
            indMil = str(indMil);
            TF = isempty(indMil);
            if TF == 1
                app.MiliaryManualR.Value = 0;
            else 
                app.MiliaryManualR.Value = 1;
            end  


            %indNodule = find(T.Classification == 'nodule(s)');
            indNodule = T.Classification == 'nodule(s)';
            indNodule = str(indNodule);
            TF = isempty(indNodule);
            if TF == 1
                app.NoduleManualR.Value = 0;
            else 
                app.NoduleManualR.Value = 1;
            end 

            indNA = T.Classification == 'no features of TB appreciated';
            indNA = str(indNA);
            TF = isempty(indNA);
            if TF == 1
                app.NoTBManualR.Value = 0;
            else 
                app.NoTBManualR.Value = 1;
            end 

            indPleuralEf = T.Classification == 'pleural effusion';
            indPleuralEf = str(indPleuralEf);
            TF = isempty(indPleuralEf);
            if TF == 1
                app.PleuralEffusionManualR.Value = 0;
            else 
                app.PleuralEffusionManualR.Value = 1;
            end 
            
   
            catch
                uiwait(msgbox('Exceeded number of images reached')); % change 
                % message if needed 
              
              answer = questdlg('Move on to next set?', ...
	'Options', ...
	'Yes','No','No');

switch answer
    case 'Yes'
       path = 'Practice/'; % Change - how??

       nowFolder = app.FoldernameEditField.Value;
       val = sscanf(nowFolder,'Set %d');
       newVal = val + 1;
       newVal = num2str(newVal);
       newFol = append ('Set ', newVal);

       app.FoldernameEditField.Value = newFol;
       app.selectedFolder = append(path, newFol);
       
        folderlist = dir(app.selectedFolder); %structure array
           app.b = {folderlist(:).name}';%cell array
           
           app.b(ismember(app.b,{'.','..'})) = [];
           app.ImagenamesDropDown.Items = app.b;
           
           cla(app.UIAxes);
           cla(app.UIAxes2);
           app.ImageEditField.Value = ' ';
           
    
    case 'No'
        
end
            end


        end

        % Button pushed function: NEXTFile
        function NEXTFileButtonPushed(app, event)
             
            app.NotesTextArea.Value = ' ';
            try 
             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
             %a = str2num(a);
             a = str2double(a);
             c = a + 1;
            % disp(c);
             d = num2str(c);
            % disp(d);
             nextIm =  append(d, '.png');
            % disp(newIm)
             app.ImagenamesDropDown.Value = nextIm;
             filename = [app.selectedFolder, '/', nextIm];             
             nextIm = imread(filename);
             app.currentIm = nextIm;
             imshow(app.currentIm, 'Parent', app.UIAxes)
             app.ImageEditField.Value = app.ImagenamesDropDown.Value;
             %app.UIAxes.Title.String = app.ImagenamesDropDown.Value;


            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
            pairedIm = append(a, '_CE.png');
            %app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');
            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
            
%             [rows, ~] = size(pairedIm);
%             %CE_OG = ROI(:, 1 : rows);
%             pairedIm = pairedIm(:, rows+1 : rows*2 );
            
            imshow(pairedIm, 'Parent', app.UIAxes2);
            app.AnnotationsSwitch.Value = 'On';
            % currently have DS store in dropdown menu 
            
%             app.HilarEnlargementManualR.Value = 0;
%             app.PleuralEffusionManualR.Value = 0;
%             app.CavitaryLesionManualR.Value = 0;
%             app.ConsolidationManualR.Value = 0;
%             app.MiliaryManualR.Value = 0;
%             app.NoduleManualR.Value = 0;

            
%             fieldNames = fieldnames(app); % gets the name list for all uicomponents as cell array
%             allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox','IgnoreCase',false)); % gets only the names contains "CheckBox" specifically.
%             for i = 1:numel(allCheckBoxNamesList) % a for loop for set all checkbox components' values as true 
%                 app.(allCheckBoxNamesList{i}).Value = true; % dynamic naming to reach elements under my app. 
%             end
%         end
%     end
            
            
            pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw=fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            data = jsondecode(str);
            %disp(data); 
            Classification = string({data.objects.classTitle})';
            T = table(Classification);


            indCav = T.Classification == 'cavitary lesion';
            indCav = str(indCav);
            TF = isempty(indCav);
            if TF == 1
                app.CavitaryLesionManualR.Value = 0;
            else 
                app.CavitaryLesionManualR.Value = 1;
            end  

            indCon = T.Classification == 'consolidation';
            indCon = str(indCon);
            TF = isempty(indCon);
            if TF == 1
                app.ConsolidationManualR.Value = 0;
            else 
                app.ConsolidationManualR.Value = 1;
            end  

            indHil = T.Classification == 'hilar lymphadenopathy';
            indHil = str(indHil);
            TF = isempty(indHil);
            if TF == 1
                app.HilarEnlargementManualR.Value = 0;
            else 
                app.HilarEnlargementManualR.Value = 1;
            end  
           

            indMil = T.Classification == 'milieary pattern';
            indMil = str(indMil);
            TF = isempty(indMil);
            if TF == 1
                app.MiliaryManualR.Value = 0;
            else 
                app.MiliaryManualR.Value = 1;
            end  


            %indNodule = find(T.Classification == 'nodule(s)');
            indNodule = T.Classification == 'nodule(s)';
            indNodule = str(indNodule);
            TF = isempty(indNodule);
            if TF == 1
                app.NoduleManualR.Value = 0;
            else 
                app.NoduleManualR.Value = 1;
            end 

            indNA = T.Classification == 'no features of TB appreciated';
            indNA = str(indNA);
            TF = isempty(indNA);
            if TF == 1
                app.NoTBManualR.Value = 0;
            else 
                app.NoTBManualR.Value = 1;
            end 

            indPleuralEf = T.Classification == 'pleural effusion';
            indPleuralEf = str(indPleuralEf);
            TF = isempty(indPleuralEf);
            if TF == 1
                app.PleuralEffusionManualR.Value = 0;
            else 
                app.PleuralEffusionManualR.Value = 1;
            end 
            
            %pairedJson = imread(pairedFileAnn);


            catch
                uiwait(msgbox('Exceeded number of images reached')); % change 
                % message if needed 
              
              answer = questdlg('Move on to next set?', ...
	'Options', ...
	'Yes','No','No');
% Handle response
switch answer
    case 'Yes'
       path = 'Practice/'; % Change - how??

       nowFolder = app.FoldernameEditField.Value;
       val = sscanf(nowFolder,'Set %d');
       newVal = val + 1;
       newVal = num2str(newVal);
       newFol = append ('Set ', newVal);

       app.FoldernameEditField.Value = newFol;
       app.selectedFolder = append(path, newFol);
       %app.nextFolder = dir(newFolPath);
       %disp(newFolPath);
       %disp(app.nextFolder);
       
        %app.nextFolder = uigetdir(); %user select folder 
            
        %[~,name] = fileparts(app.nextFolder); %gets name of folder 
        %app.FoldernameEditField.Value = name;
        folderlist = dir(app.selectedFolder); %structure array
           app.b = {folderlist(:).name}';%cell array
           
           app.b(ismember(app.b,{'.','..'})) = [];
           app.ImagenamesDropDown.Items = app.b;
           
           cla(app.UIAxes);
           cla(app.UIAxes2);
           app.ImageEditField.Value = ' ';
           
    
    case 'No'
        
end
            end


        end

        % Button pushed function: BACKFile
        function BACKFileButtonPushed(app, event)

             app.NotesTextArea.Value = ' ';
            try 
             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
            % a = str2num(a);
             a = str2double(a);
             c = a - 1 ;
            % disp(c);
             d = num2str(c);
            % disp(d);
             backIm =  append(d, '.png');
             app.ImageEditField.Value = backIm;
            % disp(newIm)
             app.ImagenamesDropDown.Value = backIm;
             filename = [app.selectedFolder, '/', backIm];             
             backIm = imread(filename);
             app.currentIm = backIm
             imshow(app.currentIm, 'Parent', app.UIAxes)
             %app.UIAxes.Title.String = app.ImagenamesDropDown.Value;


            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
            pairedIm = append(a, '_CE.png');
            %app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');
            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
%             [rows, ~] = size(pairedIm);
%             %CE_OG = ROI(:, 1 : rows);
%             pairedIm = pairedIm(:, rows+1 : rows.*2 );
            imshow(pairedIm, 'Parent', app.UIAxes2);
            app.AnnotationsSwitch.Value = 'On';
 
            catch
                msgbox('Minimum number of images reached'); % change 
                % message if needed 

            end

        end

        % Selection changed function: FinalDecisionTBButtonGroup
        function FinalDecisionTBButtonGroupSelectionChanged(app, event)
            selectedButton = app.FinalDecisionTBButtonGroup.SelectedObject;
          
         
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.NotesTextArea.Value = ' '; %clears text box 
            uiwait(msgbox('User has canceled','Success','modal')); %message 
            %saying cancellation successful.
        end

        % Button pushed function: ENLARGEButton
        function ENLARGEButtonPushed(app, event)
            figure
            imshow(imresize(app.currentIm,2));

        end

        % Value changed function: AnnotationsSwitch
        function AnnotationsSwitchValueChanged(app, event)
            %value = app.AnnotationsSwitch.Value;


            %ROI = imread('/Users/akl_rawson/Downloads/Practice/Set 1_CE/1001_CE.png');

            value = app.ImagenamesDropDown.Value;

            %filename = [app.selectedFolder, '/', value];
            %currentIm = imread(filename);     
            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
          
            pairedIm = append(a, '_CE.png');
            %app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');


            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
%             [rows, ~, ~] = size(pairedIm);
%             %double_rows = rows*2;
% 
%             CE_OG = pairedIm(:, 1 : rows);
%             CE_Ann = pairedIm(:, rows+1 : (rows*2));
            
return

            if strcmp(app.AnnotationsSwitch.Value,'On')
                %resizedCE_Ann = imresize(CE_Ann, 2);
                %imshow(resizedCE_Ann,'Parent',app.UIAxes2);
                imshow(CE_Ann,'Parent',app.UIAxes2);

            elseif strcmp(app.AnnotationsSwitch.Value, 'Off')
                
                imshow(CE_OG,'Parent',app.UIAxes2);
            end
 
%             if app.AnnotationsSwitch.Value == 'On'
%                 resizedCE_Ann = imresize(CE_Ann, 2;
%                 %imshow(resizedCE_Ann,'Parent',app.UIAxes2);
%                 imshow(CE_Ann,'Parent',app.UIAxes2);
% 
%             else
%                 resizedCE_OG = imresize(CE_OG,2);
%                 imshow(resizedCE_OG,'Parent',app.UIAxes2);
%             end
        end

        % Button pushed function: OpenExcelButton
        function OpenExcelButtonPushed(app, event)
            system('open TBnotes.xlsx');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.FolderPanel = uipanel(app.UIFigure);
            app.FolderPanel.Title = 'Folder';
            app.CHOOSEFolder = uibutton(app.FolderPanel, 'push');
            app.CHOOSEFolder.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEFolderPushed, true);
            app.CHOOSEFolder.Text = 'CHOOSE';
            app.FoldernameEditFieldLabel = uilabel(app.FolderPanel);
            app.FoldernameEditFieldLabel.Text = 'Folder name ';
            app.FoldernameEditField = uieditfield(app.FolderPanel, 'text');
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File ';
            app.BACKFile = uibutton(app.FilePanel, 'push');
            app.BACKFile.ButtonPushedFcn = createCallbackFcn(app, @BACKFileButtonPushed, true);
            app.BACKFile.Text = 'BACK';
            app.NEXTFile = uibutton(app.FilePanel, 'push');
            app.NEXTFile.ButtonPushedFcn = createCallbackFcn(app, @NEXTFileButtonPushed, true);
            app.NEXTFile.Text = 'NEXT';
            app.ImagenamesDropDownLabel = uilabel(app.FilePanel);
            app.ImagenamesDropDownLabel.Text = 'Image names';
            app.ImagenamesDropDown = uidropdown(app.FilePanel);
            app.ImagenamesDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagenamesDropDownValueChanged, true);
            app.DISPLAYFile = uibutton(app.FilePanel, 'push');
            app.DISPLAYFile.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYFilePushed, true);
            app.DISPLAYFile.Text = 'DISPLAY';
            app.ReviewPanel = uipanel(app.UIFigure);
            app.ReviewPanel.Title = 'Review';
            app.FeaturesPanel = uipanel(app.ReviewPanel);
            app.FeaturesPanel.Title = 'Features ';
            app.NoTBLabel = uilabel(app.FeaturesPanel);
            app.NoTBLabel.Text = 'No TB';
            app.NoduleLabel = uilabel(app.FeaturesPanel);
            app.NoduleLabel.Text = 'Nodule';
            app.MiliaryLabel = uilabel(app.FeaturesPanel);
            app.MiliaryLabel.Text = 'Miliary';
            app.CavitaryLesionLabel = uilabel(app.FeaturesPanel);
            app.CavitaryLesionLabel.Text = 'Cavitary Lesion';
            app.ConsolidationLabel = uilabel(app.FeaturesPanel);
            app.ConsolidationLabel.Text = 'Consolidation';
            app.PleuralEffusionLabel = uilabel(app.FeaturesPanel);
            app.PleuralEffusionLabel.Text = 'Pleural Effusion';
            app.HilarEnlargementLabel = uilabel(app.FeaturesPanel);
            app.HilarEnlargementLabel.Text = 'Hilar Enlargement';
            app.NNManualPanel = uipanel(app.ReviewPanel);
            app.NNManualPanel.Title = '  NN        Manual';
            app.NoTBNNR = uicheckbox(app.NNManualPanel);
            app.NoTBNNR.Enable = 'off';
            app.NoTBNNR.Text = '';
            app.NoduleNNR = uicheckbox(app.NNManualPanel);
            app.NoduleNNR.Enable = 'off';
            app.NoduleNNR.Text = '';
            app.MiliaryNNR = uicheckbox(app.NNManualPanel);
            app.MiliaryNNR.Enable = 'off';
            app.MiliaryNNR.Text = '';
            app.CavitaryLesionNNR = uicheckbox(app.NNManualPanel);
            app.CavitaryLesionNNR.Enable = 'off';
            app.CavitaryLesionNNR.Text = '';
            app.ConsolidationNNR = uicheckbox(app.NNManualPanel);
            app.ConsolidationNNR.Enable = 'off';
            app.ConsolidationNNR.Text = '';
            app.PleuralEffusionNNR = uicheckbox(app.NNManualPanel);
            app.PleuralEffusionNNR.Enable = 'off';
            app.PleuralEffusionNNR.Text = '';
            app.HilarEnlargementNNR = uicheckbox(app.NNManualPanel);
            app.HilarEnlargementNNR.Enable = 'off';
            app.HilarEnlargementNNR.Text = '';
            app.NoTBManualR = uicheckbox(app.NNManualPanel);
            app.NoTBManualR.Enable = 'off';
            app.NoTBManualR.Text = '';
            app.NoduleManualR = uicheckbox(app.NNManualPanel);
            app.NoduleManualR.Enable = 'off';
            app.NoduleManualR.Text = '';
            app.MiliaryManualR = uicheckbox(app.NNManualPanel);
            app.MiliaryManualR.Enable = 'off';
            app.MiliaryManualR.Text = '';
            app.ConsolidationManualR = uicheckbox(app.NNManualPanel);
            app.ConsolidationManualR.Enable = 'off';
            app.ConsolidationManualR.Text = '';
            app.CavitaryLesionManualR = uicheckbox(app.NNManualPanel);
            app.CavitaryLesionManualR.Enable = 'off';
            app.CavitaryLesionManualR.Text = '';
            app.PleuralEffusionManualR = uicheckbox(app.NNManualPanel);
            app.PleuralEffusionManualR.Enable = 'off';
            app.PleuralEffusionManualR.Text = '';
            app.HilarEnlargementManualR = uicheckbox(app.NNManualPanel);
            app.HilarEnlargementManualR.Enable = 'off';
            app.HilarEnlargementManualR.Text = '';
            app.TBX11KTBButtonGroup = uibuttongroup(app.ReviewPanel);
            app.TBX11KTBButtonGroup.Enable = 'off';
            app.TBX11KTBButtonGroup.Title = 'TBX11K TB+/-';
            app.TBPositiveGroundT = uiradiobutton(app.TBX11KTBButtonGroup);
            app.TBPositiveGroundT.Text = 'TB Negative';
            app.TBPositiveGroundT.Value = true;
            app.TBNegativeGroundT = uiradiobutton(app.TBX11KTBButtonGroup);
            app.TBNegativeGroundT.Text = 'TB Positive';
            app.FinalDecisionTBButtonGroup = uibuttongroup(app.ReviewPanel);
            app.FinalDecisionTBButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FinalDecisionTBButtonGroupSelectionChanged, true);
            app.FinalDecisionTBButtonGroup.Title = 'Final Decision TB +/-';
            app.TBPositiveFinalD = uiradiobutton(app.FinalDecisionTBButtonGroup);
            app.TBPositiveFinalD.Text = 'TB Negative';
            app.TBNegativeFinalD = uiradiobutton(app.FinalDecisionTBButtonGroup);
            app.TBNegativeFinalD.Text = 'TB Positive';
            app.TBNegativeFinalD.Value = true;
            app.SaveButton = uibutton(app.ReviewPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ReviewPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveNextButton = uibutton(app.ReviewPanel, 'push');
            app.SaveNextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNextButtonPushed, true);
            app.SaveNextButton.Text = 'Save & Next';
            app.NotesTextAreaLabel = uilabel(app.ReviewPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.ReviewPanel);
            app.OpenExcelButton = uibutton(app.ReviewPanel, 'push');
            app.OpenExcelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExcelButtonPushed, true);
            app.OpenExcelButton.Text = 'Open Excel';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.Panel);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.ENLARGEButton = uibutton(app.Panel, 'push');
            app.ENLARGEButton.ButtonPushedFcn = createCallbackFcn(app, @ENLARGEButtonPushed, true);
            app.ENLARGEButton.Text = 'ENLARGE';
            app.ImageEditField = uieditfield(app.Panel, 'text');
            app.AnnotationsSwitchLabel = uilabel(app.Panel);
            app.AnnotationsSwitchLabel.Text = 'Annotations';
            app.ImageEditFieldLabel = uilabel(app.Panel);
            app.ImageEditFieldLabel.Text = 'Image';
            app.AnnotationsSwitch = uiswitch(app.Panel, 'slider');
            app.AnnotationsSwitch.ValueChangedFcn = createCallbackFcn(app, @AnnotationsSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARMain_FolderFileImageReviewPanels_29March

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end