classdef Team5GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ReviewPanel               matlab.ui.container.Panel
        NotesTextArea             matlab.ui.control.TextArea
        NotesTextAreaLabel        matlab.ui.control.Label
        FinalDecisionButtonGroup  matlab.ui.container.ButtonGroup
        TBPosGroundT_2            matlab.ui.control.RadioButton
        TBNegGroundT_2            matlab.ui.control.RadioButton
        OpenExcelButton           matlab.ui.control.Button
        SaveNextButton            matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        NNManualPanel             matlab.ui.container.Panel
        HilarEnlargementManualR   matlab.ui.control.CheckBox
        PleuralEffusionManualR    matlab.ui.control.CheckBox
        CavitaryLesionManualR     matlab.ui.control.CheckBox
        ConsolidationManualR      matlab.ui.control.CheckBox
        MiliaryManualR            matlab.ui.control.CheckBox
        NoduleManualR             matlab.ui.control.CheckBox
        NoTBManualR               matlab.ui.control.CheckBox
        HilarEnlargementNNR       matlab.ui.control.CheckBox
        PleuralEffusionNNR        matlab.ui.control.CheckBox
        ConsolidationNNR          matlab.ui.control.CheckBox
        CavitaryLesionNNR         matlab.ui.control.CheckBox
        MiliaryNNR                matlab.ui.control.CheckBox
        NoduleNNR                 matlab.ui.control.CheckBox
        NoTBNNR                   matlab.ui.control.CheckBox
        TBX11KButtonGroup         matlab.ui.container.ButtonGroup
        TBPosGroundT              matlab.ui.control.RadioButton
        TBNegGroundT              matlab.ui.control.RadioButton
        FeaturesPanel             matlab.ui.container.Panel
        HilarEnlargementLabel     matlab.ui.control.Label
        PleuralEffusionLabel      matlab.ui.control.Label
        ConsolidationLabel        matlab.ui.control.Label
        CavitaryLesionLabel       matlab.ui.control.Label
        MiliaryLabel              matlab.ui.control.Label
        NoduleLabel               matlab.ui.control.Label
        NoTBLabel                 matlab.ui.control.Label
        ImagesPanel               matlab.ui.container.Panel
        AnnotationsSwitch         matlab.ui.control.Switch
        AnnotationsSwitchLabel    matlab.ui.control.Label
        EnlargeButton             matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        FilePanel                 matlab.ui.container.Panel
        NextButton                matlab.ui.control.Button
        BackButton                matlab.ui.control.Button
        DisplayButton             matlab.ui.control.Button
        ImagenamesDropDown        matlab.ui.control.DropDown
        ImageDropDownLabel        matlab.ui.control.Label
        FolderPanel               matlab.ui.container.Panel
        ChooseButton              matlab.ui.control.Button
        FoldernameEditField       matlab.ui.control.EditField
        FolderEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        selectedFolder; % Folder selected from Choose Button 
        pairedCEFolder; % Paired contrast enhanced folder 
        pairedAnnFolder; % Paired annotation folder 
        pairedMatFolder; % Paired neural net results folder 
        nextFolder; 
        b;
        currentIm; % current Image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            app.selectedFolder = uigetdir(); %user select folder 
            
           [~,name] = fileparts(app.selectedFolder); %gets name of folder 
           app.FoldernameEditField.Value = name;
           folderlist = dir(app.selectedFolder); %structure array
           app.b = {folderlist(:).name}';%cell array
           app.b(ismember(app.b,{'.','..'})) = [];
           app.ImagenamesDropDown.Items = app.b;
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
           %% Original Image
            value = app.ImagenamesDropDown.Value; %gets value from dropdown menu
            filename = [app.selectedFolder, '/', value]; %finds value of dropdown menu 
            % in the selected folder
            app.currentIm = imread(filename);
            imshow(app.currentIm,'Parent',app.UIAxes); %displays file in UI
            app.UIAxes.Title.String = value;
            % app.ImageEditField.Value = app.ImagenamesDropDown.Value; %changes 
            % Image name to the dropdown value 
            
            %% CE Image 
            a = value(1:4); %takes only the number from the filename 
            % (from dropdown menu) 
            pairedIm = append(a, '_CE.png'); %adds CE.png to find 
            % paired contrast-enhanced image
            app.UIAxes2.Title.String = append('Contrast Enhanced ', value);
            OGFolder = app.selectedFolder; % Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE'); % Adds _CE to original folder name
            pairedFilename = [app.pairedCEFolder, '/', pairedIm]; % Finds 
            % value in the paired contrast enhanced folder
            pairedIm = imread(pairedFilename);
            imshow(pairedIm, 'Parent', app.UIAxes2);
            axis(app.UIAxes2, 'on'); 
            hold(app.UIAxes2, 'on');

            %% TBX11K Results
            healthyIm = xlsread('/Users/akl_rawson/Downloads/Shared_Image_Key_Healthy.xlsx', 'E:E');
            imIQ = str2double(a); % Image in Question
            for i = 1:length(healthyIm)
                if imIQ ~= healthyIm(i)
                    % If image in question is not found in TBX11K Healthy
                    % Results excel, assumption is person has TB-- in other
                    % words TB Positive (TB +)
                    set(app.TBPosGroundT, 'Value', 1);                 
                end
            end
  
            %% Manual Results 
            %Find associated json file 
            pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw = fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            %Extract features 
            data = jsondecode(str);
            coords = ({data.objects.points})';  
            nfeatures = size(coords); % Number of features in the image
            % Initialize cell arrays
            featureCoords = {nfeatures};
            shapes = {nfeatures};

            Classification = string({data.objects.classTitle})';
            T = table(Classification);
           
            for i = 1:nfeatures   % Loop through the features in the image
                axis(app.UIAxes2, 'on'); 
                hold( app.UIAxes2, 'on');
                featureCoords{i} = coords{i}.exterior;    % Grab all the coordinates
                [h,~] = size(featureCoords{i});
                if  h == 2        % If feature is onlt two points, it's a bounding box (nodule)
                bbox = featureCoords{i}; % JSON gives lower left point, width, and height. 
                rect = bbox2points([bbox(1),bbox(3),bbox(2)-bbox(1),bbox(4)-bbox(3)]); % Generate vertices from JSON info
                pgon(i) = polyshape(rect);
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#77AC30','FaceColor', 'none', 'LineWidth', 1.2);   
                hold(app.UIAxes2, 'on');
                else
                pgon(i) = polyshape(featureCoords{i}); % If not nodule, then figure out which feature, then plot with corresponding color
                if Classification(i,1) == 'cavitary lesion'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#EDB120','FaceColor', 'none', 'LineWidth', 1.2);
                
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'consolidation'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#7E2F8E','FaceColor', 'none', 'LineWidth', 1.2); 
               
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'hilar lymphadenopathy'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#4DBEEE','FaceColor', 'none', 'LineWidth', 1.2);
               
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'miliary pattern'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#A2142F','FaceColor', 'none', 'LineWidth', 1.2);
              
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) ==  'pleural effusion'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#D95319','FaceColor', 'none', 'LineWidth', 1.2);
                
                hold(app.UIAxes2, 'on');
                end
                axis(app.UIAxes2, 'off'); 
                hold(app.UIAxes2, 'off');
                
        
                end
            end
            
            %Features Check boxes 
            indCav = T.Classification == 'cavitary lesion'; 
            indCav = str(indCav);
            TF = isempty(indCav);
            if TF == 1
                app.CavitaryLesionManualR.Value = 0; %
            else 
                app.CavitaryLesionManualR.Value = 1;
            end  

            indCon = T.Classification == 'consolidation';
            indCon = str(indCon);
            TF = isempty(indCon);
            if TF == 1
                app.ConsolidationManualR.Value = 0;
            else 
                app.ConsolidationManualR.Value = 1;
            end  

            indHil = T.Classification == 'hilar lymphadenopathy';
            indHil = str(indHil);
            TF = isempty(indHil);
            if TF == 1
                app.HilarEnlargementManualR.Value = 0;
            else 
                app.HilarEnlargementManualR.Value = 1;
            end  
           

            indMil = T.Classification == 'milieary pattern';
            indMil = str(indMil);
            TF = isempty(indMil);
            if TF == 1
                app.MiliaryManualR.Value = 0;
            else 
                app.MiliaryManualR.Value = 1;
            end  

            indNodule = T.Classification == 'nodule(s)';
            indNodule = str(indNodule);
            TF = isempty(indNodule);
            if TF == 1
                app.NoduleManualR.Value = 0;
            else 
                app.NoduleManualR.Value = 1;
            end 

            indNA = T.Classification == 'no features of TB appreciated';
            indNA = str(indNA);
            TF = isempty(indNA);
            if TF == 1
                app.NoTBManualR.Value = 0;
            else 
                app.NoTBManualR.Value = 1;
            end

            indPleuralEf = T.Classification == 'pleural effusion';
            indPleuralEf = str(indPleuralEf);
            TF = isempty(indPleuralEf);
            if TF == 1
                app.PleuralEffusionManualR.Value = 0;
            else
                app.PleuralEffusionManualR.Value = 1;
            end
            %% Neural Net Results
            app.pairedMatFolder = '/Users/akl_rawson/Downloads/OneDrive_2_4-14-2022-2'; %specific to my computer
            a = str2double(a) - 1000; %starts from 0000
            if a < 10
                %if less than 10, tb000
                a = num2str(a);
                pairedMat = append('tb000',a,'_ann.mat');
                pairedMat = [app.pairedMatFolder, '/', pairedMat]
                load(pairedMat);
            elseif a >= 10
                %if greater than or equal to 10, tb00
                a = num2str(a);
                pairedMat = append('tb00',a,'_ann.mat');
                pairedMat = [app.pairedMatFolder, '/', pairedMat];
                load(pairedMat);
            elseif a >= 100
                %if greater than or equal to 100, tb0
                a = num2str(a);
                pairedMat = append('tb0',a,'_ann.mat');
                pairedMat = [app.pairedMatFolder, '/', pairedMat];
                load(pairedMat);
            elseif a >= 1000
                %if greater than or equal to 1000, tb
                a = num2str(a);
                pairedMat = append('tb',a,'_ann.mat');
                pairedMat = [app.pairedMatFolder, '/', pairedMat];
                load(pairedMat);
            end

% Loop over the classes
for classIdx = 1:6

    % Create a mask of the class
    mask = (ann == classIdx);
    if max(max(mask)) == 0

        % If the class is empty go to the next
        continue
    else

        % Find objects
        cc = bwconncomp(mask);

        % Loop over objects
        for objIdx = 1:cc.NumObjects

            % Create a mask of the object
            objMsk = false(size(mask));
            pixList = cc.PixelIdxList{objIdx};
            nPix = length(pixList);

            for idx = 1:length(cc.PixelIdxList{objIdx})
                
                objMsk(pixList(idx)) = true;

            end

            % Trace object boundaries to form polyshape
            objMsk = bwboundaries(objMsk);
            shape = polyshape(objMsk{1}(:,2), objMsk{1}(:,1));
            % Check box according to the class
            if classIdx == 1
                % Consolidation
                app.ConsolidationNNR.Value = 1;
            elseif classIdx == 2
                % Cavitation
               app.CavitaryLesionNNR.Value = 1;    
            elseif classIdx == 3
                % Effusion
                app.PleuralEffusionNNR.Value = 1; 
            elseif classIdx == 4
                % Lymphadenopathy
                app.HilarEnlargementNNR.Value = 1;      
            elseif classIdx == 5
                % Miliary pattern
                app.MiliaryNNR.Value = 1;     
            elseif classIdx == 6
                % Nodule
                app.NoduleNNR.Value = 1;
            end

        end          

    end

end


        end

        % Value changed function: AnnotationsSwitch
        function AnnotationsSwitchValueChanged(app, event)
             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
          
            pairedIm = append(a, '_CE.png');
            %app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');


            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);

            if strcmp(app.AnnotationsSwitch.Value,'On')
                %resizedCE_Ann = imresize(CE_Ann, 2);
                %imshow(resizedCE_Ann,'Parent',app.UIAxes2);
                %imshow(CE_Ann,'Parent',app.UIAxes2);
                pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw = fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            data = jsondecode(str);
            coords = ({data.objects.points})';  
            nfeatures = size(coords); % Number of features in the image
            % Initialize cell arrays
            featureCoords = {nfeatures};
            shapes = {nfeatures};

            Classification = string({data.objects.classTitle})';
            T = table(Classification);
           
            for i = 1:nfeatures   % Loop through the features in the image
                axis(app.UIAxes2, 'on'); 
                hold( app.UIAxes2, 'on');
                featureCoords{i} = coords{i}.exterior;    % Grab all the coordinates
                [h,~] = size(featureCoords{i});
                if  h == 2        % If feature is onlt two points, it's a bounding box (nodule)
                bbox = featureCoords{i}; % JSON gives lower left point, width, and height. 
                rect = bbox2points([bbox(1),bbox(3),bbox(2)-bbox(1),bbox(4)-bbox(3)]); % Generate vertices from JSON info
                pgon(i) = polyshape(rect);
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#77AC30','FaceColor', 'none', 'LineWidth', 1.2);   
                hold(app.UIAxes2, 'on');
                else
                pgon(i) = polyshape(featureCoords{i}); % If not nodule, then figure out which feature, then plot with corresponding color
                if Classification(i,1) == 'cavitary lesion'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#EDB120','FaceColor', 'none', 'LineWidth', 1.2);
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'consolidation'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#7E2F8E','FaceColor', 'none', 'LineWidth', 1.2); 
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'hilar lymphadenopathy'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#4DBEEE','FaceColor', 'none', 'LineWidth', 1.2);
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'miliary pattern'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#A2142F','FaceColor', 'none', 'LineWidth', 1.2);
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) ==  'pleural effusion'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#D95319','FaceColor', 'none', 'LineWidth', 1.2);
                hold(app.UIAxes2, 'on');
                end
                axis(app.UIAxes2, 'off'); 
                hold(app.UIAxes2, 'off');
                end
            end

            elseif strcmp(app.AnnotationsSwitch.Value, 'Off')
                
                imshow(pairedIm,'Parent',app.UIAxes2);
            end
        end

        % Button pushed function: EnlargeButton
        function EnlargeButtonPushed(app, event)
            figure
            imshow(imresize(app.currentIm,2));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            folder = app.FoldernameEditField.Value; %need to extract only smallest folder
            finalD = app.FinalDecisionButtonGroup.SelectedObject.Text;           
            Time = now; 
            dateTime = datetime(Time,'ConvertFrom','datenum'); 
            % In case user wants to know last date they worked on  
            dateTime = datestr(dateTime);   
            fileName = app.ImagenamesDropDown.Value;
            setNumber = sscanf(folder,'Set %d');
            setNumber = string(setNumber);
            notes = app.NotesTextArea.Value;
            notesDecision = [fileName finalD notes dateTime]; 
            writecell(notesDecision,'TBnotes.xlsx','Sheet',setNumber, 'WriteMode', 'append'); % The name of excel file is TBnotes
            uiwait(msgbox('Notes and Decision Saved', 'Success', 'modal'));

        end

        % Button pushed function: OpenExcelButton
        function OpenExcelButtonPushed(app, event)
            system('open TBnotes.xlsx'); %works with mac
%           winopen('TBnotes.xlsx'); %works with windows 
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
             app.NotesTextArea.Value = ' ';
            try 
             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
             a = str2double(a);
             c = a - 1 ;
             d = num2str(c);
             backIm =  append(d, '.png');
             app.ImagenamesDropDown.Value = backIm;
             filename = [app.selectedFolder, '/', backIm];             
             backIm = imread(filename);
             app.currentIm = backIm;
             imshow(app.currentIm, 'Parent', app.UIAxes);
             app.UIAxes.Title.String = app.ImagenamesDropDown.Value;
            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
            pairedIm = append(a, '_CE.png');
            app.UIAxes2.Title.String = pairedIm;        
            OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');
            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
            imshow(pairedIm, 'Parent', app.UIAxes2);
            app.AnnotationsSwitch.Value = 'On';
 
            catch
                msgbox('Minimum number of images reached'); % change 
                % message if needed 

            end

        end

        % Button pushed function: SaveNextButton
        function SaveNextButtonPushed(app, event)
            folder = app.FoldernameEditField.Value;

            finalD = app.FinalDecisionButtonGroup.SelectedObject.Text;


            Time = now;
            dateTime = datetime(Time,'ConvertFrom','datenum'); %in case user wants to know last date they worked
            dateTime = datestr(dateTime);


            fileName = app.ImagenamesDropDown.Value;
            setNumber = sscanf(folder,'Set %d');
            setNumber = string(setNumber);

            notes = app.NotesTextArea.Value;

            notesDecision = [fileName finalD notes dateTime];
            writecell(notesDecision,'TBnotes.xlsx','Sheet',setNumber, 'WriteMode', 'append');
            uiwait(msgbox('Notes and Decision Saved', 'Success', 'modal'));

            app.NotesTextArea.Value = ' ';
            try 
                
             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
             a = str2double(a);
             c = a + 1;
             d = num2str(c);
             nextIm =  append(d, '.png');
             app.ImagenamesDropDown.Value = nextIm;
             filename = [app.selectedFolder, '/', nextIm];             
             nextIm = imread(filename);
             imshow(nextIm, 'Parent', app.UIAxes)
             app.UIAxes.Title.String = app.ImagenamesDropDown.Value;


             a = app.ImagenamesDropDown.Value;
             a = a(1:4);
             pairedIm = append(a, '_CE.png');
             app.UIAxes2.Title.String = pairedIm;
            
             OGFolder = app.selectedFolder; %Selected folder pathname; change to folder_CE
             app.pairedCEFolder = append(OGFolder, '_CE');
             pairedFilename = [app.pairedCEFolder, '/', pairedIm];
             pairedIm = imread(pairedFilename);
            
             imshow(pairedIm, 'Parent', app.UIAxes2);
            

            %% Json file
            app.AnnotationsSwitch.Value = 'On';
            pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw=fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            data = jsondecode(str);
            %disp(data); 
            Classification = string({data.objects.classTitle})';
            T = table(Classification);


            indCav = T.Classification == 'cavitary lesion';
            indCav = str(indCav);
            TF = isempty(indCav);
            if TF == 1
                app.CavitaryLesionManualR.Value = 0;
            else 
                app.CavitaryLesionManualR.Value = 1;
            end  

            indCon = T.Classification == 'consolidation';
            indCon = str(indCon);
            TF = isempty(indCon);
            if TF == 1
                app.ConsolidationManualR.Value = 0;
            else 
                app.ConsolidationManualR.Value = 1;
            end  

            indHil = T.Classification == 'hilar lymphadenopathy';
            indHil = str(indHil);
            TF = isempty(indHil);
            if TF == 1
                app.HilarEnlargementManualR.Value = 0;
            else 
                app.HilarEnlargementManualR.Value = 1;
            end  
           

            indMil = T.Classification == 'milieary pattern';
            indMil = str(indMil);
            TF = isempty(indMil);
            if TF == 1
                app.MiliaryManualR.Value = 0;
            else 
                app.MiliaryManualR.Value = 1;
            end  

            indNodule = T.Classification == 'nodule(s)';
            indNodule = str(indNodule);
            TF = isempty(indNodule);
            if TF == 1
                app.NoduleManualR.Value = 0;
            else 
                app.NoduleManualR.Value = 1;
            end 

            indNA = T.Classification == 'no features of TB appreciated';
            indNA = str(indNA);
            TF = isempty(indNA);
            if TF == 1
                app.NoTBManualR.Value = 0;
            else 
                app.NoTBManualR.Value = 1;
            end 

            indPleuralEf = T.Classification == 'pleural effusion';
            indPleuralEf = str(indPleuralEf);
            TF = isempty(indPleuralEf);
            if TF == 1
                app.PleuralEffusionManualR.Value = 0;
            else 
                app.PleuralEffusionManualR.Value = 1;
            end 
            
   
            catch
                uiwait(msgbox('Exceeded number of images reached')); % change 
                % message if needed 
              
              answer = questdlg('Move on to next set?', ...
	'Options', ...
	'Yes','No','No');

switch answer
    case 'Yes'
        path = 'Practice/'; % Change - how??

        nowFolder = app.FoldernameEditField.Value;
        val = sscanf(nowFolder,'Set %d'); % Reads data 
        newVal = val + 1; % Adds 1 to original folder number 
        newVal = num2str(newVal); % Converts to string
        newFol = append ('Set ', newVal); % Append Set to beginning of string

        app.FoldernameEditField.Value = newFol;
        app.selectedFolder = append(path, newFol);
       
        folderlist = dir(app.selectedFolder); 
        app.b = {folderlist(:).name}';
           
        app.b(ismember(app.b,{'.','..'})) = [];
        app.ImagenamesDropDown.Items = app.b;
           
        %Set all checkboxes back to zero 
        app.NoTBManualR.Value = 0;
        app.NoTBNNR.Value = 0;
        app.CavitaryLesionManualR.Value = 0;
        app.CavitaryLesionNNR.Value = 0;
        app.ConsolidationManualR.Value = 0;
        app.ConsolidationNNR.Value = 0;
        app.NoduleManualR.Value = 0;
        app.NoduleNNR.Value = 0;
        app.PleuralEffusionManualR.Value = 0;
        app.PleuralEffusionNNR.Value = 0;
        app.MiliaryManualR.Value = 0;
        app.MiliaryNNR.Value = 0;
        app.HilarEnlargementManualR.Value = 0;
        app.HilarEnlargementNNR.Value =0;
        cla(app.UIAxes);
        cla(app.UIAxes2);
           
    
    case 'No'
        
end
            end

              
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
   app.NotesTextArea.Value = ' ';
            try 
             a = app.ImagenamesDropDown.Value;
             a = a(1:4); % Get only the numbers from filename 
             a = str2double(a);
             c = a + 1; % Add 1, to go to the next file
             d = num2str(c);
             nextIm =  append(d, '.png');
             app.ImagenamesDropDown.Value = nextIm;
             filename = [app.selectedFolder, '/', nextIm];             
             nextIm = imread(filename);
             app.currentIm = nextIm;
             imshow(app.currentIm, 'Parent', app.UIAxes) 
             app.UIAxes.Title.String = app.ImagenamesDropDown.Value;


            a = app.ImagenamesDropDown.Value;
            a = a(1:4);
            pairedIm = append(a, '_CE.png');
            app.UIAxes2.Title.String = pairedIm;
            
            OGFolder = app.selectedFolder; % Selected folder pathname; change to folder_CE
            app.pairedCEFolder = append(OGFolder, '_CE');
            pairedFilename = [app.pairedCEFolder, '/', pairedIm];
            pairedIm = imread(pairedFilename);
            
            
            imshow(pairedIm, 'Parent', app.UIAxes2);
            app.AnnotationsSwitch.Value = 'On';

            
  %% Manual results
            pairedJson = append(a, '.png.json');
            app.pairedAnnFolder = append(OGFolder, '_ann');
            pairedFileAnn = [app.pairedAnnFolder, '/', pairedJson];
            fid = fopen(pairedFileAnn); 
            raw = fread(fid,inf); 
            str = char(raw');
            fclose(fid); 
            data = jsondecode(str);
            coords = ({data.objects.points})';  
            nfeatures = size(coords); % Number of features in the image
            % Initialize cell arrays
            featureCoords = {nfeatures};
            shapes = {nfeatures};

            Classification = string({data.objects.classTitle})';
            T = table(Classification);
           
            for i = 1:nfeatures   % Loop through the features in the image
                axis(app.UIAxes2, 'on'); 
                hold( app.UIAxes2, 'on');
                featureCoords{i} = coords{i}.exterior;    % Grab all the coordinates
                [h,~] = size(featureCoords{i});
                if  h == 2        % If feature is onlt two points, it's a bounding box (nodule)
                bbox = featureCoords{i}; % JSON gives lower left point, width, and height. 
                rect = bbox2points([bbox(1),bbox(3),bbox(2)-bbox(1),bbox(4)-bbox(3)]); % Generate vertices from JSON info
                pgon(i) = polyshape(rect);
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#77AC30','FaceColor', 'none', 'LineWidth', 1.2);   
                hold(app.UIAxes2, 'on');
                else
                pgon(i) = polyshape(featureCoords{i}); % If not nodule, then figure out which feature, then plot with corresponding color
                if Classification(i,1) == 'cavitary lesion'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#EDB120','FaceColor', 'none', 'LineWidth', 1.2);
                
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'consolidation'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#7E2F8E','FaceColor', 'none', 'LineWidth', 1.2); 
               
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'hilar lymphadenopathy'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#4DBEEE','FaceColor', 'none', 'LineWidth', 1.2);
               
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) == 'miliary pattern'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#A2142F','FaceColor', 'none', 'LineWidth', 1.2);
              
                hold(app.UIAxes2, 'on');
                elseif Classification(i,1) ==  'pleural effusion'
                plot(pgon(i),'Parent', app.UIAxes2,'EdgeColor','#D95319','FaceColor', 'none', 'LineWidth', 1.2);
                
                hold(app.UIAxes2, 'on');
                end
                axis(app.UIAxes2, 'off'); 
                hold(app.UIAxes2, 'off');
                
        
                end
            end
            app.AnnotationsSwitch.Value = 'On'


            indCav = T.Classification == 'cavitary lesion';
            indCav = str(indCav);
            TF = isempty(indCav);
            if TF == 1
                app.CavitaryLesionManualR.Value = 0;
            else 
                app.CavitaryLesionManualR.Value = 1;
            end  

            indCon = T.Classification == 'consolidation';
            indCon = str(indCon);
            TF = isempty(indCon);
            if TF == 1
                app.ConsolidationManualR.Value = 0;
            else 
                app.ConsolidationManualR.Value = 1;
            end  

            indHil = T.Classification == 'hilar lymphadenopathy';
            indHil = str(indHil);
            TF = isempty(indHil);
            if TF == 1
                app.HilarEnlargementManualR.Value = 0;
            else 
                app.HilarEnlargementManualR.Value = 1;
            end  
           

            indMil = T.Classification == 'milieary pattern';
            indMil = str(indMil);
            TF = isempty(indMil);
            if TF == 1
                app.MiliaryManualR.Value = 0;
            else 
                app.MiliaryManualR.Value = 1;
            end  

            
            indNodule = T.Classification == 'nodule(s)';
            indNodule = str(indNodule);
            TF = isempty(indNodule);
            if TF == 1
                app.NoduleManualR.Value = 0;
            else 
                app.NoduleManualR.Value = 1;
            end 

            indNA = T.Classification == 'no features of TB appreciated';
            indNA = str(indNA);
            TF = isempty(indNA);
            if TF == 1
                app.NoTBManualR.Value = 0;
            else 
                app.NoTBManualR.Value = 1;
            end 

            indPleuralEf = T.Classification == 'pleural effusion';
            indPleuralEf = str(indPleuralEf);
            TF = isempty(indPleuralEf);
            if TF == 1
                app.PleuralEffusionManualR.Value = 0;
            else 
                app.PleuralEffusionManualR.Value = 1;
            end
            %% Neural Net Results 
app.pairedMatFolder = '/Users/akl_rawson/Downloads/OneDrive_2_4-14-2022-2'; %specific to my computer 
a = str2double(a) - 3000; %starts from 0000
if a < 10 
    % If less than 10, tb000 
    a = num2str(a);
    pairedMat = append('tb000',a,'_ann.mat'); 
    pairedMat = [app.pairedMatFolder, '/', pairedMat]
    load(pairedMat);
elseif a >= 10
    % If greater than or equal to 10, tb00
    a = num2str(a);
    pairedMat = append('tb00',a,'_ann.mat');
    pairedMat = [app.pairedMatFolder, '/', pairedMat];
    load(pairedMat);
elseif a >= 100
    % If greater than or equal to 100, tb0
    a = num2str(a);
    pairedMat = append('tb0',a,'_ann.mat');
    pairedMat = [app.pairedMatFolder, '/', pairedMat];
    load(pairedMat);
elseif a >= 1000
    % If greater than or equal to 1000, tb 
    a = num2str(a);
    pairedMat = append('tb',a,'_ann.mat');
    pairedMat = [app.pairedMatFolder, '/', pairedMat];
    load(pairedMat);
end 

% Loop over the classes
for classIdx = 1:6

    % Create a mask of the class
    mask = (ann == classIdx);
    if max(max(mask)) == 0

        % If the class is empty go to the next
        continue
    else

        % Find objects
        cc = bwconncomp(mask);

        % Loop over objects
        for objIdx = 1:cc.NumObjects

            % Create a mask of the object
            objMsk = false(size(mask));
            pixList = cc.PixelIdxList{objIdx};
            nPix = length(pixList);

            for idx = 1:length(cc.PixelIdxList{objIdx})
                
                objMsk(pixList(idx)) = true;

            end

            % Trace object boundaries to form polyshape
            objMsk = bwboundaries(objMsk);
            shape = polyshape(objMsk{1}(:,2), objMsk{1}(:,1));
            % Check box according to the class
            if classIdx == 1
                % Consolidation
                app.ConsolidationNNR.Value = 1;
            elseif classIdx == 2
                % Cavitation
               app.CavitaryLesionNNR.Value = 1;    
            elseif classIdx == 3
                % Effusion
                app.PleuralEffusionNNR.Value = 1; 
            elseif classIdx == 4
                % Lymphadenopathy
                app.HilarEnlargementNNR.Value = 1;      
            elseif classIdx == 5
                % Miliary pattern
                app.MiliaryNNR.Value = 1;     
            elseif classIdx == 6
                % Nodule
                app.NoduleNNR.Value = 1;
            end

        end          

    end

end
           
            catch
                uiwait(msgbox('Exceeded number of images reached')); % change 
                % message if needed 
              
              answer = questdlg('Move on to next set?', ...
	            'Options','Yes','No','No');
% Handle response
switch answer
    case 'Yes' 
       path = 'Practice/'; % Change - how??

       nowFolder = app.FoldernameEditField.Value;
       val = sscanf(nowFolder,'Set %d');
       newVal = val + 1;
       newVal = num2str(newVal);
       newFol = append ('Set ', newVal);

       app.FoldernameEditField.Value = newFol;
       app.selectedFolder = append(path, newFol);
       folderlist = dir(app.selectedFolder); %structure array
       app.b = {folderlist(:).name}';%cell array
           
       app.b(ismember(app.b,{'.','..'})) = [];
       app.ImagenamesDropDown.Items = app.b;
       %Set all checkboxes back to zero 
       app.NoTBManualR.Value = 0;
       app.NoTBNNR.Value = 0;
       app.CavitaryLesionManualR.Value = 0;
       app.CavitaryLesionNNR.Value = 0;
       app.ConsolidationManualR.Value = 0;
       app.ConsolidationNNR.Value = 0;
       app.NoduleManualR.Value = 0;
       app.NoduleNNR.Value = 0; 
       app.PleuralEffusionManualR.Value = 0;
       app.PleuralEffusionNNR.Value = 0;
       app.MiliaryManualR.Value = 0;
       app.MiliaryNNR.Value = 0;
       app.HilarEnlargementManualR.Value = 0;
       app.HilarEnlargementNNR.Value =0; 
       cla(app.UIAxes); 
       cla(app.UIAxes2); 
       app.UIAxes.Title.String = ' ';
       app.UIAxes2.Title.String = ' ';
    
    case 'No'
        
end
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.NotesTextArea.Value = ' '; %clears text box 
            uiwait(msgbox('User has canceled','Success','modal')); %message 
            %saying cancellation successful.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FolderPanel = uipanel(app.UIFigure);
            app.FolderPanel.Title = 'Folder ';
            app.FolderEditFieldLabel = uilabel(app.FolderPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FoldernameEditField = uieditfield(app.FolderPanel, 'text');
            app.FoldernameEditField.Editable = 'off';
            app.ChooseButton = uibutton(app.FolderPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File ';
            app.ImageDropDownLabel = uilabel(app.FilePanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImagenamesDropDown = uidropdown(app.FilePanel);
            app.DisplayButton = uibutton(app.FilePanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.BackButton = uibutton(app.FilePanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.FilePanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ImagesPanel = uipanel(app.UIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ImagesPanel);
            title(app.UIAxes2, 'Title')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.EnlargeButton = uibutton(app.ImagesPanel, 'push');
            app.EnlargeButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeButtonPushed, true);
            app.EnlargeButton.Text = 'Enlarge';
            app.AnnotationsSwitchLabel = uilabel(app.ImagesPanel);
            app.AnnotationsSwitchLabel.Text = 'Annotations';
            app.AnnotationsSwitch = uiswitch(app.ImagesPanel, 'slider');
            app.AnnotationsSwitch.ValueChangedFcn = createCallbackFcn(app, @AnnotationsSwitchValueChanged, true);
            app.ReviewPanel = uipanel(app.UIFigure);
            app.ReviewPanel.Title = 'Review ';
            app.FeaturesPanel = uipanel(app.ReviewPanel);
            app.FeaturesPanel.Title = 'Features ';
            app.NoTBLabel = uilabel(app.FeaturesPanel);
            app.NoTBLabel.Text = 'No TB';
            app.NoduleLabel = uilabel(app.FeaturesPanel);
            app.NoduleLabel.Text = 'Nodule';
            app.MiliaryLabel = uilabel(app.FeaturesPanel);
            app.MiliaryLabel.Text = 'Miliary';
            app.CavitaryLesionLabel = uilabel(app.FeaturesPanel);
            app.CavitaryLesionLabel.Text = 'Cavitary Lesion';
            app.ConsolidationLabel = uilabel(app.FeaturesPanel);
            app.ConsolidationLabel.Text = 'Consolidation';
            app.PleuralEffusionLabel = uilabel(app.FeaturesPanel);
            app.PleuralEffusionLabel.Text = 'Pleural Effusion';
            app.HilarEnlargementLabel = uilabel(app.FeaturesPanel);
            app.HilarEnlargementLabel.Text = 'Hilar Enlargement';
            app.TBX11KButtonGroup = uibuttongroup(app.ReviewPanel);
            app.TBX11KButtonGroup.Enable = 'off';
            app.TBX11KButtonGroup.Title = 'TBX11K';
            app.TBNegGroundT = uiradiobutton(app.TBX11KButtonGroup);
            app.TBNegGroundT.Text = 'TB-';
            app.TBNegGroundT.Value = true;
            app.TBPosGroundT = uiradiobutton(app.TBX11KButtonGroup);
            app.TBPosGroundT.Text = 'TB+';
            app.NNManualPanel = uipanel(app.ReviewPanel);
            app.NNManualPanel.Title = '     NN        Manual';
            app.NoTBNNR = uicheckbox(app.NNManualPanel);
            app.NoTBNNR.Enable = 'off';
            app.NoTBNNR.Text = '';
            app.NoduleNNR = uicheckbox(app.NNManualPanel);
            app.NoduleNNR.Enable = 'off';
            app.NoduleNNR.Text = '';
            app.MiliaryNNR = uicheckbox(app.NNManualPanel);
            app.MiliaryNNR.Enable = 'off';
            app.MiliaryNNR.Text = '';
            app.CavitaryLesionNNR = uicheckbox(app.NNManualPanel);
            app.CavitaryLesionNNR.Enable = 'off';
            app.CavitaryLesionNNR.Text = '';
            app.ConsolidationNNR = uicheckbox(app.NNManualPanel);
            app.ConsolidationNNR.Enable = 'off';
            app.ConsolidationNNR.Text = '';
            app.PleuralEffusionNNR = uicheckbox(app.NNManualPanel);
            app.PleuralEffusionNNR.Enable = 'off';
            app.PleuralEffusionNNR.Text = '';
            app.HilarEnlargementNNR = uicheckbox(app.NNManualPanel);
            app.HilarEnlargementNNR.Enable = 'off';
            app.HilarEnlargementNNR.Text = '';
            app.NoTBManualR = uicheckbox(app.NNManualPanel);
            app.NoTBManualR.Enable = 'off';
            app.NoTBManualR.Text = '';
            app.NoduleManualR = uicheckbox(app.NNManualPanel);
            app.NoduleManualR.Enable = 'off';
            app.NoduleManualR.Text = '';
            app.MiliaryManualR = uicheckbox(app.NNManualPanel);
            app.MiliaryManualR.Enable = 'off';
            app.MiliaryManualR.Text = '';
            app.ConsolidationManualR = uicheckbox(app.NNManualPanel);
            app.ConsolidationManualR.Enable = 'off';
            app.ConsolidationManualR.Text = '';
            app.CavitaryLesionManualR = uicheckbox(app.NNManualPanel);
            app.CavitaryLesionManualR.Enable = 'off';
            app.CavitaryLesionManualR.Text = '';
            app.PleuralEffusionManualR = uicheckbox(app.NNManualPanel);
            app.PleuralEffusionManualR.Enable = 'off';
            app.PleuralEffusionManualR.Text = '';
            app.HilarEnlargementManualR = uicheckbox(app.NNManualPanel);
            app.HilarEnlargementManualR.Enable = 'off';
            app.HilarEnlargementManualR.Text = '';
            app.SaveButton = uibutton(app.ReviewPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ReviewPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveNextButton = uibutton(app.ReviewPanel, 'push');
            app.SaveNextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNextButtonPushed, true);
            app.SaveNextButton.Text = 'Save & Next';
            app.OpenExcelButton = uibutton(app.ReviewPanel, 'push');
            app.OpenExcelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExcelButtonPushed, true);
            app.OpenExcelButton.Text = 'Open Excel';
            app.FinalDecisionButtonGroup = uibuttongroup(app.ReviewPanel);
            app.FinalDecisionButtonGroup.Title = 'Final Decision';
            app.TBNegGroundT_2 = uiradiobutton(app.FinalDecisionButtonGroup);
            app.TBNegGroundT_2.Text = 'TB-';
            app.TBNegGroundT_2.Value = true;
            app.TBPosGroundT_2 = uiradiobutton(app.FinalDecisionButtonGroup);
            app.TBPosGroundT_2.Text = 'TB+';
            app.NotesTextAreaLabel = uilabel(app.ReviewPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.ReviewPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Team5GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end